classdef ImageRetrievalApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        CannyEdgeLabel          matlab.ui.control.Label
        OriginalLabel           matlab.ui.control.Label
        ImageDataLabel          matlab.ui.control.Label
        ImageRetrievalAppLabel  matlab.ui.control.Label
        ChooseanImageButton     matlab.ui.control.Button
        CannyImage              matlab.ui.control.UIAxes
        SelectedImage           matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChooseanImageButton
        function ChooseanImageButtonPushed(app, event)
            global imgCanny
            [filename, pathname] =uigetfile('*.*','Choose an Image');
            filename=strcat(pathname,filename);
            imgChoice=imread(filename); % Selects filename from search
            imshow(imgChoice, 'Parent', app.SelectedImage); % Displays image
            imgCanny=edge(imgChoice,'Canny'); % Puts image into Canny Edge
            imshow(imgCanny, 'Parent', app.CannyImage);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectedImage = uiaxes(app.UIFigure);
            app.SelectedImage.XTick = [];
            app.SelectedImage.YTick = [];
            app.CannyImage = uiaxes(app.UIFigure);
            app.CannyImage.XTick = [];
            app.CannyImage.YTick = [];
            app.ChooseanImageButton = uibutton(app.UIFigure, 'push');
            app.ChooseanImageButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseanImageButtonPushed, true);
            app.ChooseanImageButton.Text = 'Choose an Image';
            app.ImageRetrievalAppLabel = uilabel(app.UIFigure);
            app.ImageRetrievalAppLabel.Text = 'Image Retrieval App';
            app.ImageDataLabel = uilabel(app.UIFigure);
            app.ImageDataLabel.Text = 'Image Data';
            app.OriginalLabel = uilabel(app.UIFigure);
            app.OriginalLabel.Text = 'Original';
            app.CannyEdgeLabel = uilabel(app.UIFigure);
            app.CannyEdgeLabel.Text = 'Canny Edge';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageRetrievalApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end